classdef GUI_Video < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        GridLayout4          matlab.ui.container.GridLayout
        GridLayout6          matlab.ui.container.GridLayout
        GoButton             matlab.ui.control.Button
        FrameNaranja         matlab.ui.control.UIAxes
        AllMasks             matlab.ui.control.UIAxes
        FrameVerde           matlab.ui.control.UIAxes
        FrameAmarillo        matlab.ui.control.UIAxes
        Frame                matlab.ui.control.UIAxes
        GridLayout2          matlab.ui.container.GridLayout
        GridLayout3          matlab.ui.container.GridLayout
        BienvenidoaSRSLabel  matlab.ui.control.Label
        Image                matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
           
             v = VideoReader('videoMarcadores.mp4');

            % revisar for 
            for i=1: v .NumFrames
                tic; % revisar si esto está bien 
                
                f=readFrame(v); % num frames, v está en el instante x
                [BN,mN] = createMaskN(f); % naranja
                [BV,mV] = createMaskV(f); % verde
                [BA,mA] = createMaskA(f); % amarillo

                BN_ao = bwareaopen(BN,355); % 355
                BV_ao = bwareaopen(BV,398); 
                BA_ao = bwareaopen(BA,420);

                se=strel('disk',50); % disk tamaño probar con 50

                BN_cl = imclose(BN_ao,se);
                BV_cl = imclose(BV_ao,se);
                BA_cl = imclose(BA_ao,se);

                %mascara aplicada en color
                 mN = bsxfun(@times,f,cast(BN_cl,'like',f));
                 mV = bsxfun(@times,f,cast(BV_cl,'like',f));
                 mA = bsxfun(@times,f,cast(BA_cl,'like',f));
                
                imshow(f,'Parent',app.Frame);
                imshow( mN,'Parent',app.FrameNaranja);
                imshow( mV,'Parent',app.FrameVerde);
                imshow( mA,'Parent',app.FrameAmarillo);

                % para mostrar todas las mascaras
%                 mAll = mN |mV | mA;
%                 imshow(mAll,'Parent',app.AllMasks);
                drawnow;
                t = toc;
                pause(1-t);
                

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageSource = 'foto.png';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.BienvenidoaSRSLabel = uilabel(app.GridLayout3);
            app.BienvenidoaSRSLabel.Text = 'Bienvenido a SRS';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.Frame = uiaxes(app.GridLayout4);
            title(app.Frame, 'Frame')
            app.Frame.XTick = [];
            app.Frame.XTickLabel = '';
            app.Frame.YTick = [];
            app.Frame.YTickLabel = '';
            app.Frame.Tag = 'Frame';
            app.FrameAmarillo = uiaxes(app.GridLayout4);
            title(app.FrameAmarillo, 'Amarillo')
            app.FrameAmarillo.XTick = [];
            app.FrameAmarillo.YTick = [];
            app.FrameAmarillo.Tag = 'FrameAmarillo';
            app.FrameVerde = uiaxes(app.GridLayout4);
            title(app.FrameVerde, 'Verde')
            app.FrameVerde.XTick = [];
            app.FrameVerde.XTickLabel = '';
            app.FrameVerde.YTick = [];
            app.FrameVerde.YTickLabel = '';
            app.FrameVerde.Tag = 'FrameVerde';
            app.AllMasks = uiaxes(app.GridLayout4);
            title(app.AllMasks, 'All Masks')
            app.AllMasks.XTick = [];
            app.AllMasks.XTickLabel = '';
            app.AllMasks.YTick = [];
            app.AllMasks.YTickLabel = '';
            app.AllMasks.Tag = 'AllMasks';
            app.FrameNaranja = uiaxes(app.GridLayout4);
            title(app.FrameNaranja, 'Naranja')
            app.FrameNaranja.XTick = [];
            app.FrameNaranja.YTick = [];
            app.FrameNaranja.Tag = 'FrameNaranja';
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.GoButton = uibutton(app.GridLayout6, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Tag = 'GoButton';
            app.GoButton.Text = 'Go';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Video

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end