classdef GUI_graficas_new4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        GridLayout8                   matlab.ui.container.GridLayout
        TobilloLabel                  matlab.ui.control.Label
        RodillaLabel                  matlab.ui.control.Label
        CaderaLabel                   matlab.ui.control.Label
        LeyendaLabel                  matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        GrficasconjuntasTab           matlab.ui.container.Tab
        GridLayout7                   matlab.ui.container.GridLayout
        MovimientodelpacienteLabel    matlab.ui.control.Label
        MovimientoidealLabel          matlab.ui.control.Label
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        GrficascaderaTab              matlab.ui.container.Tab
        GridLayout7_2                 matlab.ui.container.GridLayout
        MovimientodelpacienteLabel_2  matlab.ui.control.Label
        MovimientoidealLabel_2        matlab.ui.control.Label
        UIAxes2_2                     matlab.ui.control.UIAxes
        UIAxes3_2                     matlab.ui.control.UIAxes
        UIAxes4_2                     matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        GrficasrodillaTab             matlab.ui.container.Tab
        GridLayout7_3                 matlab.ui.container.GridLayout
        MovimientodelpacienteLabel_3  matlab.ui.control.Label
        MovimientoidealLabel_3        matlab.ui.control.Label
        UIAxes2_3                     matlab.ui.control.UIAxes
        UIAxes3_3                     matlab.ui.control.UIAxes
        UIAxes4_3                     matlab.ui.control.UIAxes
        UIAxes_3                      matlab.ui.control.UIAxes
        GrficastobilloTab             matlab.ui.container.Tab
        GridLayout7_4                 matlab.ui.container.GridLayout
        MovimientodelpacienteLabel_4  matlab.ui.control.Label
        MovimientoidealLabel_4        matlab.ui.control.Label
        UIAxes2_4                     matlab.ui.control.UIAxes
        UIAxes3_4                     matlab.ui.control.UIAxes
        UIAxes4_4                     matlab.ui.control.UIAxes
        UIAxes_4                      matlab.ui.control.UIAxes
        GridLayout3                   matlab.ui.container.GridLayout
        BienvenidoaSRSLabel           matlab.ui.control.Label
        GridLayout2                   matlab.ui.container.GridLayout
        GridLayout4                   matlab.ui.container.GridLayout
        AvatarButton                  matlab.ui.control.Button
        InformeButton                 matlab.ui.control.Button
        GridLayout7_5                 matlab.ui.container.GridLayout
        InformacindelacapturaButton   matlab.ui.control.Button
        IniciarButton                 matlab.ui.control.Button
        Switch_2                      matlab.ui.control.Switch
        BorrarButton                  matlab.ui.control.Button
        PausarButton                  matlab.ui.control.Button
        GraficasLabel                 matlab.ui.control.Label
        GridLayout6                   matlab.ui.container.GridLayout
        ApellidosLabel2               matlab.ui.control.Label
        ApellidosLabel                matlab.ui.control.Label
        FechaLabel2                   matlab.ui.control.Label
        PesoLabel2                    matlab.ui.control.Label
        EdadLabel2                    matlab.ui.control.Label
        NombreLabel2                  matlab.ui.control.Label
        FechaLabel                    matlab.ui.control.Label
        PesoLabel                     matlab.ui.control.Label
        EdadLabel                     matlab.ui.control.Label
        NombreLabel                   matlab.ui.control.Label
        ImageLogo                     matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global nombre apellidos edad peso;

            %load datos.mat nombre apellido edad peso;

            app.NombreLabel2.Text = strcat(nombre);
            app.ApellidosLabel2.Text = strcat(apellidos);
            app.EdadLabel2.Text = strcat(int2str(edad), " años");
            app.PesoLabel2.Text = strcat(num2str(peso) , "kg");

            formato = 'dd/mm/yy';
            app.FechaLabel2.Text = datestr(now,formato);
        end

        % Button pushed function: AvatarButton
        function AvatarButtonPushed(app, event)
            global nombre apellidos edad peso;
            GUI_avatarAlfa;
            app.delete;
        end

        % Button pushed function: InformeButton
        function InformeButtonPushed(app, event)
            global nombre apellidos edad peso;
            GUI_informe;
            app.delete;
        end

        % Button pushed function: IniciarButton
        function Iniciar(app, event)
            
        end

        % Button pushed function: PausarButton
        function Pausar(app, event)
            
        end

        % Button pushed function: BorrarButton
        function Borrar(app, event)
            
        end

        % Button pushed function: InformacindelacapturaButton
        function InformacionCaptura(app, event)
            
        end

        % Button down function: GrficasconjuntasTab
        function GraficasConjuntas(app, event)
            
        end

        % Button down function: GrficascaderaTab
        function GraficasCadera(app, event)
            
        end

        % Button down function: GrficasrodillaTab
        function GraficasRodilla(app, event)
            
        end

        % Button down function: GrficastobilloTab
        function GraficasTobillo(app, event)
            
        end

        % Value changed function: Switch_2
        function SwitchSubidaBajada(app, event)
            value = app.Switch_2.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ImageLogo = uiimage(app.GridLayout);
            app.ImageLogo.Tag = 'imageLogo';
            app.ImageLogo.ImageSource = 'foto.png';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.NombreLabel = uilabel(app.GridLayout6);
            app.NombreLabel.Tag = 'labelNombre';
            app.NombreLabel.Text = 'Nombre:';
            app.EdadLabel = uilabel(app.GridLayout6);
            app.EdadLabel.Tag = 'labelEdad';
            app.EdadLabel.Text = 'Edad:';
            app.PesoLabel = uilabel(app.GridLayout6);
            app.PesoLabel.Tag = 'labelPeso';
            app.PesoLabel.Text = 'Peso:';
            app.FechaLabel = uilabel(app.GridLayout6);
            app.FechaLabel.Tag = 'labelFecha';
            app.FechaLabel.Text = 'Fecha:';
            app.NombreLabel2 = uilabel(app.GridLayout6);
            app.NombreLabel2.Tag = 'labelNombre2';
            app.NombreLabel2.Text = '';
            app.EdadLabel2 = uilabel(app.GridLayout6);
            app.EdadLabel2.Tag = 'labelEdad2';
            app.EdadLabel2.Text = '';
            app.PesoLabel2 = uilabel(app.GridLayout6);
            app.PesoLabel2.Tag = 'labelPeso2';
            app.PesoLabel2.Text = '';
            app.FechaLabel2 = uilabel(app.GridLayout6);
            app.FechaLabel2.Tag = 'labelFecha2';
            app.FechaLabel2.Text = '';
            app.ApellidosLabel = uilabel(app.GridLayout6);
            app.ApellidosLabel.Tag = 'labelApellidos';
            app.ApellidosLabel.Text = 'Apellidos:';
            app.ApellidosLabel2 = uilabel(app.GridLayout6);
            app.ApellidosLabel2.Tag = 'labelApellidos2';
            app.ApellidosLabel2.Text = '';
            app.GridLayout7_5 = uigridlayout(app.GridLayout2);
            app.GraficasLabel = uilabel(app.GridLayout7_5);
            app.GraficasLabel.Tag = 'labelCapturaMovimiento';
            app.GraficasLabel.Text = 'Captura de movimiento';
            app.PausarButton = uibutton(app.GridLayout7_5, 'push');
            app.PausarButton.ButtonPushedFcn = createCallbackFcn(app, @Pausar, true);
            app.PausarButton.Tag = 'buttonPausar';
            app.PausarButton.Text = 'Pausar';
            app.BorrarButton = uibutton(app.GridLayout7_5, 'push');
            app.BorrarButton.ButtonPushedFcn = createCallbackFcn(app, @Borrar, true);
            app.BorrarButton.Tag = 'buttonBorrar';
            app.BorrarButton.Text = 'Borrar';
            app.Switch_2 = uiswitch(app.GridLayout7_5, 'slider');
            app.Switch_2.Items = {'Subida', 'Bajada'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @SwitchSubidaBajada, true);
            app.Switch_2.Tag = 'switchSubidaBajada';
            app.Switch_2.Value = 'Subida';
            app.IniciarButton = uibutton(app.GridLayout7_5, 'push');
            app.IniciarButton.ButtonPushedFcn = createCallbackFcn(app, @Iniciar, true);
            app.IniciarButton.Tag = 'buttonIniciar';
            app.IniciarButton.Text = 'Iniciar';
            app.InformacindelacapturaButton = uibutton(app.GridLayout7_5, 'push');
            app.InformacindelacapturaButton.ButtonPushedFcn = createCallbackFcn(app, @InformacionCaptura, true);
            app.InformacindelacapturaButton.Tag = 'buttonInformacionCaptura';
            app.InformacindelacapturaButton.Text = 'Información de la captura';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.InformeButton = uibutton(app.GridLayout4, 'push');
            app.InformeButton.ButtonPushedFcn = createCallbackFcn(app, @InformeButtonPushed, true);
            app.InformeButton.Tag = 'buttonInforme';
            app.InformeButton.Text = 'Informe';
            app.AvatarButton = uibutton(app.GridLayout4, 'push');
            app.AvatarButton.ButtonPushedFcn = createCallbackFcn(app, @AvatarButtonPushed, true);
            app.AvatarButton.Tag = 'buttonAvatar';
            app.AvatarButton.Text = 'Avatar';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.BienvenidoaSRSLabel = uilabel(app.GridLayout3);
            app.BienvenidoaSRSLabel.Tag = 'labelBienvenido';
            app.BienvenidoaSRSLabel.Text = 'Bienvenido a SRS';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.GrficasconjuntasTab = uitab(app.TabGroup);
            app.GrficasconjuntasTab.Title = 'Gráficas conjuntas';
            app.GrficasconjuntasTab.ButtonDownFcn = createCallbackFcn(app, @GraficasConjuntas, true);
            app.GrficasconjuntasTab.Tag = 'tabGraficasConjuntas';
            app.GridLayout7 = uigridlayout(app.GrficasconjuntasTab);
            app.UIAxes = uiaxes(app.GridLayout7);
            title(app.UIAxes, 'Subida')
            xlabel(app.UIAxes, 'Tiempo (ms)')
            ylabel(app.UIAxes, 'Ángulo (º)')
            app.UIAxes.Tag = 'axesSubidaIdealConjuntas';
            app.UIAxes4 = uiaxes(app.GridLayout7);
            title(app.UIAxes4, 'Bajada')
            xlabel(app.UIAxes4, 'Tiempo (ms)')
            ylabel(app.UIAxes4, 'Ángulo (º)')
            app.UIAxes4.Tag = 'axesBajadaPacienteConjuntas';
            app.UIAxes3 = uiaxes(app.GridLayout7);
            title(app.UIAxes3, 'Subida')
            xlabel(app.UIAxes3, 'Tiempo (ms)')
            ylabel(app.UIAxes3, 'Ángulo (º)')
            app.UIAxes3.Tag = 'axesSubidaPacienteConjuntas';
            app.UIAxes2 = uiaxes(app.GridLayout7);
            title(app.UIAxes2, 'Bajada')
            xlabel(app.UIAxes2, 'Tiempo (ms)')
            ylabel(app.UIAxes2, 'Ángulo (º)')
            app.UIAxes2.Tag = 'axesBajadaIdealConjuntas';
            app.MovimientoidealLabel = uilabel(app.GridLayout7);
            app.MovimientoidealLabel.Tag = 'labelMovimientoIdealConjuntas';
            app.MovimientoidealLabel.Text = 'Movimiento ideal';
            app.MovimientodelpacienteLabel = uilabel(app.GridLayout7);
            app.MovimientodelpacienteLabel.Tag = 'labelMovimientoPacienteConjuntas';
            app.MovimientodelpacienteLabel.Text = 'Movimiento del paciente';
            app.GrficascaderaTab = uitab(app.TabGroup);
            app.GrficascaderaTab.Title = 'Gráficas cadera';
            app.GrficascaderaTab.ButtonDownFcn = createCallbackFcn(app, @GraficasCadera, true);
            app.GrficascaderaTab.Tag = 'tabGraficasCadera';
            app.GridLayout7_2 = uigridlayout(app.GrficascaderaTab);
            app.UIAxes_2 = uiaxes(app.GridLayout7_2);
            title(app.UIAxes_2, 'Subida')
            xlabel(app.UIAxes_2, 'Tiempo (ms)')
            ylabel(app.UIAxes_2, 'Ángulo (º)')
            app.UIAxes_2.Tag = 'axesSubidaIdealCadera';
            app.UIAxes4_2 = uiaxes(app.GridLayout7_2);
            title(app.UIAxes4_2, 'Bajada')
            xlabel(app.UIAxes4_2, 'Tiempo (ms)')
            ylabel(app.UIAxes4_2, 'Ángulo (º)')
            app.UIAxes4_2.Tag = 'axesBajadaPacienteCadera';
            app.UIAxes3_2 = uiaxes(app.GridLayout7_2);
            title(app.UIAxes3_2, 'Subida')
            xlabel(app.UIAxes3_2, 'Tiempo (ms)')
            ylabel(app.UIAxes3_2, 'Ángulo (º)')
            app.UIAxes3_2.Tag = 'axesSubidaPacienteCadera';
            app.UIAxes2_2 = uiaxes(app.GridLayout7_2);
            title(app.UIAxes2_2, 'Bajada')
            xlabel(app.UIAxes2_2, 'Tiempo (ms)')
            ylabel(app.UIAxes2_2, 'Ángulo (º)')
            app.UIAxes2_2.Tag = 'axesBajadaIdealCadera';
            app.MovimientoidealLabel_2 = uilabel(app.GridLayout7_2);
            app.MovimientoidealLabel_2.Tag = 'labelMovimientoIdealCadera';
            app.MovimientoidealLabel_2.Text = 'Movimiento ideal';
            app.MovimientodelpacienteLabel_2 = uilabel(app.GridLayout7_2);
            app.MovimientodelpacienteLabel_2.Tag = 'labelMovimientoPacienteCadera';
            app.MovimientodelpacienteLabel_2.Text = 'Movimiento del paciente';
            app.GrficasrodillaTab = uitab(app.TabGroup);
            app.GrficasrodillaTab.Title = 'Gráficas rodilla';
            app.GrficasrodillaTab.ButtonDownFcn = createCallbackFcn(app, @GraficasRodilla, true);
            app.GrficasrodillaTab.Tag = 'tabGraficasRodilla';
            app.GridLayout7_3 = uigridlayout(app.GrficasrodillaTab);
            app.UIAxes_3 = uiaxes(app.GridLayout7_3);
            title(app.UIAxes_3, 'Subida')
            xlabel(app.UIAxes_3, 'Tiempo (ms)')
            ylabel(app.UIAxes_3, 'Ángulo (º)')
            app.UIAxes_3.Tag = 'axesSubidaIdealRodilla';
            app.UIAxes4_3 = uiaxes(app.GridLayout7_3);
            title(app.UIAxes4_3, 'Bajada')
            xlabel(app.UIAxes4_3, 'Tiempo (ms)')
            ylabel(app.UIAxes4_3, 'Ángulo (º)')
            app.UIAxes4_3.Tag = 'axesBajadaPacienteRodilla';
            app.UIAxes3_3 = uiaxes(app.GridLayout7_3);
            title(app.UIAxes3_3, 'Subida')
            xlabel(app.UIAxes3_3, 'Tiempo (ms)')
            ylabel(app.UIAxes3_3, 'Ángulo (º)')
            app.UIAxes3_3.Tag = 'axesSubidaPacienteRodilla';
            app.UIAxes2_3 = uiaxes(app.GridLayout7_3);
            title(app.UIAxes2_3, 'Bajada')
            xlabel(app.UIAxes2_3, 'Tiempo (ms)')
            ylabel(app.UIAxes2_3, 'Ángulo (º)')
            app.UIAxes2_3.Tag = 'axesBajadaIdealRodilla';
            app.MovimientoidealLabel_3 = uilabel(app.GridLayout7_3);
            app.MovimientoidealLabel_3.Tag = 'labelMovimientoIdealRodilla';
            app.MovimientoidealLabel_3.Text = 'Movimiento ideal';
            app.MovimientodelpacienteLabel_3 = uilabel(app.GridLayout7_3);
            app.MovimientodelpacienteLabel_3.Tag = 'labelMovimientoPacienteRodilla';
            app.MovimientodelpacienteLabel_3.Text = 'Movimiento del paciente';
            app.GrficastobilloTab = uitab(app.TabGroup);
            app.GrficastobilloTab.Title = 'Gráficas tobillo';
            app.GrficastobilloTab.ButtonDownFcn = createCallbackFcn(app, @GraficasTobillo, true);
            app.GrficastobilloTab.Tag = 'tabGraficasTobillo';
            app.GridLayout7_4 = uigridlayout(app.GrficastobilloTab);
            app.UIAxes_4 = uiaxes(app.GridLayout7_4);
            title(app.UIAxes_4, 'Subida')
            xlabel(app.UIAxes_4, 'Tiempo (ms)')
            ylabel(app.UIAxes_4, 'Ángulo (º)')
            app.UIAxes_4.Tag = 'axesSubidaIdealTobillo';
            app.UIAxes4_4 = uiaxes(app.GridLayout7_4);
            title(app.UIAxes4_4, 'Bajada')
            xlabel(app.UIAxes4_4, 'Tiempo (ms)')
            ylabel(app.UIAxes4_4, 'Ángulo (º)')
            app.UIAxes4_4.Tag = 'axesBajadaPacienteTobillo';
            app.UIAxes3_4 = uiaxes(app.GridLayout7_4);
            title(app.UIAxes3_4, 'Subida')
            xlabel(app.UIAxes3_4, 'Tiempo (ms)')
            ylabel(app.UIAxes3_4, 'Ángulo (º)')
            app.UIAxes3_4.Tag = 'axesSubidaPacienteTobillo';
            app.UIAxes2_4 = uiaxes(app.GridLayout7_4);
            title(app.UIAxes2_4, 'Bajada')
            xlabel(app.UIAxes2_4, 'Tiempo (ms)')
            ylabel(app.UIAxes2_4, 'Ángulo (º)')
            app.UIAxes2_4.Tag = 'axesBajadaIdealTobillo';
            app.MovimientoidealLabel_4 = uilabel(app.GridLayout7_4);
            app.MovimientoidealLabel_4.Tag = 'labelMovimientoIdealTobillo';
            app.MovimientoidealLabel_4.Text = 'Movimiento ideal';
            app.MovimientodelpacienteLabel_4 = uilabel(app.GridLayout7_4);
            app.MovimientodelpacienteLabel_4.Tag = 'labelMovimientoPacienteTobillo';
            app.MovimientodelpacienteLabel_4.Text = 'Movimiento del paciente';
            app.GridLayout8 = uigridlayout(app.GridLayout);
            app.LeyendaLabel = uilabel(app.GridLayout8);
            app.LeyendaLabel.Tag = 'labeLeyenda';
            app.LeyendaLabel.Text = 'Leyenda:';
            app.CaderaLabel = uilabel(app.GridLayout8);
            app.CaderaLabel.Tag = 'labelLeyendaCadera';
            app.CaderaLabel.Text = 'Cadera';
            app.RodillaLabel = uilabel(app.GridLayout8);
            app.RodillaLabel.Tag = 'labelLeyendaRodilla';
            app.RodillaLabel.Text = 'Rodilla';
            app.TobilloLabel = uilabel(app.GridLayout8);
            app.TobilloLabel.Tag = 'labelLeyendaTobillo';
            app.TobilloLabel.Text = 'Tobillo';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_graficas_new4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end