classdef GUI_informe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        GridLayout5                matlab.ui.container.GridLayout
        PrametrosPanel             matlab.ui.container.Panel
        ngulosdeltobilloPanel      matlab.ui.container.Panel
        FlexionplantarLabelMAX     matlab.ui.control.Label
        FlexionplantarLabelMIN     matlab.ui.control.Label
        DorsiflexionLabelMAX       matlab.ui.control.Label
        DorsiflexionLabelMIN       matlab.ui.control.Label
        MAX_TobilloLabel           matlab.ui.control.Label
        MIN_TobilloLabel           matlab.ui.control.Label
        FlexionplantarLabel        matlab.ui.control.Label
        DorsiflexionLabel          matlab.ui.control.Label
        ngulosdelarodillaPanel     matlab.ui.container.Panel
        Extension_RodillaLabelMAX  matlab.ui.control.Label
        Extension_RodillaLabelMIN  matlab.ui.control.Label
        Flexion_RodillaLabelMAX    matlab.ui.control.Label
        Flexion_RodillaLabelMIN    matlab.ui.control.Label
        MAX_RodillaLabel           matlab.ui.control.Label
        MIN_RodillaLabel           matlab.ui.control.Label
        Extension_RodillaLabel     matlab.ui.control.Label
        Flexion_RodillaLabel       matlab.ui.control.Label
        ngulosdelacaderaPanel      matlab.ui.container.Panel
        Extension_CaderaLabelMAX   matlab.ui.control.Label
        Extension_CaderaLabelMIN   matlab.ui.control.Label
        Flexion_CaderaLabelMAX     matlab.ui.control.Label
        Flexion_CaderaLabelMIN     matlab.ui.control.Label
        MIN_CaderaLabel            matlab.ui.control.Label
        MAX_CaderaLabel            matlab.ui.control.Label
        Extension_CaderaLabel      matlab.ui.control.Label
        Flexion_CaderaLabel        matlab.ui.control.Label
        TiempoReal_Label2          matlab.ui.control.Label
        TiempoReal_Label1          matlab.ui.control.Label
        TiempoIdeal_Label2         matlab.ui.control.Label
        TiempoIdeal_Label1         matlab.ui.control.Label
        Hyperlink                  matlab.ui.control.Hyperlink
        InformebiomecnicodelpacienteLabel  matlab.ui.control.Label
        GridLayout3                matlab.ui.container.GridLayout
        BienvenidoaSRSLabel        matlab.ui.control.Label
        GridLayout2                matlab.ui.container.GridLayout
        GridLayout6                matlab.ui.container.GridLayout
        FechaLabel2                matlab.ui.control.Label
        PesoLabel2                 matlab.ui.control.Label
        EdadLabel2                 matlab.ui.control.Label
        PacienteLabel2             matlab.ui.control.Label
        FechaLabel                 matlab.ui.control.Label
        PesokgLabel                matlab.ui.control.Label
        EdadLabel                  matlab.ui.control.Label
        PacienteLabel              matlab.ui.control.Label
        GridLayout7                matlab.ui.container.GridLayout
        GrficasButton              matlab.ui.control.Button
        AvatarButton               matlab.ui.control.Button
        ImageLogo                  matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global nombre apellido edad peso;

            %load datos.mat nombre apellido edad peso;

            app.PacienteLabel2.Text = strcat(nombre," ",apellido);
            app.EdadLabel2.Text = int2str(edad);
            app.PesoLabel2.Text = num2str(peso);
            
            formato = 'dd/mm/yy';
            app.FechaLabel2.Text = datestr(now,formato);
        end

        % Button pushed function: GrficasButton
        function GrficasButtonPushed(app, event)
            global nombre apellido edad peso;
            GUI_graficas;
            app.delete;
        end

        % Button pushed function: AvatarButton
        function AvatarButtonPushed(app, event)
            global nombre apellido edad peso;
            GUI_avatarAlfa;
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ImageLogo = uiimage(app.GridLayout);
            app.ImageLogo.Tag = 'imageLogo';
            app.ImageLogo.ImageSource = 'foto.png';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout7 = uigridlayout(app.GridLayout2);
            app.AvatarButton = uibutton(app.GridLayout7, 'push');
            app.AvatarButton.ButtonPushedFcn = createCallbackFcn(app, @AvatarButtonPushed, true);
            app.AvatarButton.Tag = 'buttonAvatar';
            app.AvatarButton.Text = 'Avatar';
            app.GrficasButton = uibutton(app.GridLayout7, 'push');
            app.GrficasButton.ButtonPushedFcn = createCallbackFcn(app, @GrficasButtonPushed, true);
            app.GrficasButton.Tag = 'buttonGráficas';
            app.GrficasButton.Text = 'Gráficas';
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.PacienteLabel = uilabel(app.GridLayout6);
            app.PacienteLabel.Tag = 'labelPaciente';
            app.PacienteLabel.Text = 'Paciente:';
            app.EdadLabel = uilabel(app.GridLayout6);
            app.EdadLabel.Tag = 'labelEdad';
            app.EdadLabel.Text = 'Edad:';
            app.PesokgLabel = uilabel(app.GridLayout6);
            app.PesokgLabel.Tag = 'labelPeso';
            app.PesokgLabel.Text = 'Peso (kg):';
            app.FechaLabel = uilabel(app.GridLayout6);
            app.FechaLabel.Tag = 'labelFecha';
            app.FechaLabel.Text = 'Fecha:';
            app.PacienteLabel2 = uilabel(app.GridLayout6);
            app.PacienteLabel2.Tag = 'labelPaciente2';
            app.PacienteLabel2.Text = '';
            app.EdadLabel2 = uilabel(app.GridLayout6);
            app.EdadLabel2.Tag = 'labelEdad2';
            app.EdadLabel2.Text = '';
            app.PesoLabel2 = uilabel(app.GridLayout6);
            app.PesoLabel2.Tag = 'labelPeso2';
            app.PesoLabel2.Text = '';
            app.FechaLabel2 = uilabel(app.GridLayout6);
            app.FechaLabel2.Tag = 'labelFecha2';
            app.FechaLabel2.Text = '';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.BienvenidoaSRSLabel = uilabel(app.GridLayout3);
            app.BienvenidoaSRSLabel.Tag = 'labelBienvenido';
            app.BienvenidoaSRSLabel.Text = 'Bienvenido a SRS';
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.InformebiomecnicodelpacienteLabel = uilabel(app.GridLayout5);
            app.InformebiomecnicodelpacienteLabel.Tag = 'labelInforme';
            app.InformebiomecnicodelpacienteLabel.Text = 'Informe biomecánico del paciente';
            app.Hyperlink = uihyperlink(app.GridLayout5);
            app.Hyperlink.Tag = 'hypDescargar';
            app.Hyperlink.Text = 'Descargar';
            app.PrametrosPanel = uipanel(app.GridLayout5);
            app.PrametrosPanel.Title = 'Párametros';
            app.TiempoIdeal_Label1 = uilabel(app.PrametrosPanel);
            app.TiempoIdeal_Label1.Tag = 'TiempoIdeal_Label1';
            app.TiempoIdeal_Label1.Text = 'Tiempo ideal:';
            app.TiempoIdeal_Label2 = uilabel(app.PrametrosPanel);
            app.TiempoIdeal_Label2.Tag = 'TiempoIdeal_Label2';
            app.TiempoIdeal_Label2.Text = '';
            app.TiempoReal_Label1 = uilabel(app.PrametrosPanel);
            app.TiempoReal_Label1.Tag = 'TiempoReal_Label1';
            app.TiempoReal_Label1.Text = 'Tiempo real:';
            app.TiempoReal_Label2 = uilabel(app.PrametrosPanel);
            app.TiempoReal_Label2.Tag = 'TiempoReal_Label2';
            app.TiempoReal_Label2.Text = '';
            app.ngulosdelacaderaPanel = uipanel(app.PrametrosPanel);
            app.ngulosdelacaderaPanel.Title = 'Ángulos de la cadera';
            app.ngulosdelacaderaPanel.Tag = 'a_CaderaLabel';
            app.Flexion_CaderaLabel = uilabel(app.ngulosdelacaderaPanel);
            app.Flexion_CaderaLabel.Tag = 'Flexion_CaderaLabel';
            app.Flexion_CaderaLabel.Text = 'Flexión:';
            app.Extension_CaderaLabel = uilabel(app.ngulosdelacaderaPanel);
            app.Extension_CaderaLabel.Tag = 'Extension_CaderaLabel';
            app.Extension_CaderaLabel.Text = 'Extensión:';
            app.MAX_CaderaLabel = uilabel(app.ngulosdelacaderaPanel);
            app.MAX_CaderaLabel.Tag = 'MAX_CaderaLabel';
            app.MAX_CaderaLabel.Text = 'MAX';
            app.MIN_CaderaLabel = uilabel(app.ngulosdelacaderaPanel);
            app.MIN_CaderaLabel.Tag = 'MIN_CaderaLabel';
            app.MIN_CaderaLabel.Text = 'MIN';
            app.Flexion_CaderaLabelMIN = uilabel(app.ngulosdelacaderaPanel);
            app.Flexion_CaderaLabelMIN.Text = '';
            app.Flexion_CaderaLabelMAX = uilabel(app.ngulosdelacaderaPanel);
            app.Flexion_CaderaLabelMAX.Text = '';
            app.Extension_CaderaLabelMIN = uilabel(app.ngulosdelacaderaPanel);
            app.Extension_CaderaLabelMIN.Text = '';
            app.Extension_CaderaLabelMAX = uilabel(app.ngulosdelacaderaPanel);
            app.Extension_CaderaLabelMAX.Text = '';
            app.ngulosdelarodillaPanel = uipanel(app.PrametrosPanel);
            app.ngulosdelarodillaPanel.Title = 'Ángulos de la rodilla';
            app.ngulosdelarodillaPanel.Tag = 'a_RodillaLabel';
            app.Flexion_RodillaLabel = uilabel(app.ngulosdelarodillaPanel);
            app.Flexion_RodillaLabel.Text = 'Flexión:';
            app.Extension_RodillaLabel = uilabel(app.ngulosdelarodillaPanel);
            app.Extension_RodillaLabel.Tag = 'Extension_RodillaLabel';
            app.Extension_RodillaLabel.Text = 'Extensión:';
            app.MIN_RodillaLabel = uilabel(app.ngulosdelarodillaPanel);
            app.MIN_RodillaLabel.Tag = 'MIN_RodillaLabel';
            app.MIN_RodillaLabel.Text = 'MIN';
            app.MAX_RodillaLabel = uilabel(app.ngulosdelarodillaPanel);
            app.MAX_RodillaLabel.Tag = 'MAX_RodillaLabel';
            app.MAX_RodillaLabel.Text = 'MAX';
            app.Flexion_RodillaLabelMIN = uilabel(app.ngulosdelarodillaPanel);
            app.Flexion_RodillaLabelMIN.Text = '';
            app.Flexion_RodillaLabelMAX = uilabel(app.ngulosdelarodillaPanel);
            app.Flexion_RodillaLabelMAX.Text = '';
            app.Extension_RodillaLabelMIN = uilabel(app.ngulosdelarodillaPanel);
            app.Extension_RodillaLabelMIN.Text = '';
            app.Extension_RodillaLabelMAX = uilabel(app.ngulosdelarodillaPanel);
            app.Extension_RodillaLabelMAX.Text = '';
            app.ngulosdeltobilloPanel = uipanel(app.PrametrosPanel);
            app.ngulosdeltobilloPanel.Title = 'Ángulos del tobillo';
            app.ngulosdeltobilloPanel.Tag = 'a_TobilloLabel';
            app.DorsiflexionLabel = uilabel(app.ngulosdeltobilloPanel);
            app.DorsiflexionLabel.Tag = 'DorsiflexionLabel';
            app.DorsiflexionLabel.Text = 'Dorsiflexión:';
            app.FlexionplantarLabel = uilabel(app.ngulosdeltobilloPanel);
            app.FlexionplantarLabel.Tag = 'FlexionplantarLabel';
            app.FlexionplantarLabel.Text = 'Flexión plantar:';
            app.MIN_TobilloLabel = uilabel(app.ngulosdeltobilloPanel);
            app.MIN_TobilloLabel.Tag = 'MIN_TobilloLabel';
            app.MIN_TobilloLabel.Text = 'MIN';
            app.MAX_TobilloLabel = uilabel(app.ngulosdeltobilloPanel);
            app.MAX_TobilloLabel.Tag = 'MAX_TobilloLabel';
            app.MAX_TobilloLabel.Text = 'MAX';
            app.DorsiflexionLabelMIN = uilabel(app.ngulosdeltobilloPanel);
            app.DorsiflexionLabelMIN.Text = '';
            app.DorsiflexionLabelMAX = uilabel(app.ngulosdeltobilloPanel);
            app.DorsiflexionLabelMAX.Text = '';
            app.FlexionplantarLabelMIN = uilabel(app.ngulosdeltobilloPanel);
            app.FlexionplantarLabelMIN.Text = '';
            app.FlexionplantarLabelMAX = uilabel(app.ngulosdeltobilloPanel);
            app.FlexionplantarLabelMAX.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_informe

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end